classdef IMUVisApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        AccPlot     matlab.ui.control.UIAxes
        GyroPlot    matlab.ui.control.UIAxes
        MagPlot     matlab.ui.control.UIAxes
        KalmanPlot  matlab.ui.control.UIAxes
    end


    methods (Access = public)
        
        function UpdateApp(~, q, plotName)
            q = q';
            rotm = quat2rotm(q);

            X = [1 0 0] * rotm;
            Y = [0 1 0] * rotm;
            Z = [0 0 1] * rotm;
            
            plot3(plotName, [0 X(1)], [0 X(2)], [0 X(3)])
            hold(plotName,'on')
            plot3(plotName, [0 Y(1)], [0 Y(2)], [0 Y(3)])
            plot3(plotName, [0 Z(1)], [0 Z(2)], [0 Z(3)])
            hold(plotName,'off')
        end
    end
    

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AccPlot = uiaxes(app.UIFigure);
            title(app.AccPlot, 'Accelerometer data only')
            xlabel(app.AccPlot, 'X')
            ylabel(app.AccPlot, 'Y')
            zlabel(app.AccPlot, 'Z')
            app.AccPlot.XLim = [-1 1];
            app.AccPlot.YLim = [-1 1];
            app.AccPlot.ZLim = [-1 1];
            app.GyroPlot = uiaxes(app.UIFigure);
            title(app.GyroPlot, 'Gyro data only')
            xlabel(app.GyroPlot, 'X')
            ylabel(app.GyroPlot, 'Y')
            zlabel(app.GyroPlot, 'Z')
            app.GyroPlot.XLim = [-1 1];
            app.GyroPlot.YLim = [-1 1];
            app.GyroPlot.ZLim = [-1 1];
            app.MagPlot = uiaxes(app.UIFigure);
            title(app.MagPlot, 'Mag data only')
            xlabel(app.MagPlot, 'X')
            ylabel(app.MagPlot, 'Y')
            zlabel(app.MagPlot, 'Z')
            app.MagPlot.XLim = [-1 1];
            app.MagPlot.YLim = [-1 1];
            app.MagPlot.ZLim = [-1 1];
            app.KalmanPlot = uiaxes(app.UIFigure);
            title(app.KalmanPlot, 'Kalman fused data')
            xlabel(app.KalmanPlot, 'X')
            ylabel(app.KalmanPlot, 'Y')
            zlabel(app.KalmanPlot, 'Z')
            app.KalmanPlot.XLim = [-1 1];
            app.KalmanPlot.YLim = [-1 1];
            app.KalmanPlot.ZLim = [-1 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IMUVisApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end